classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        EncodeTab                  matlab.ui.container.Tab
        InputEditFieldLabel        matlab.ui.control.Label
        input                      matlab.ui.control.EditField
        CompressionAlgorithmDropDownLabel  matlab.ui.control.Label
        modeSelect                 matlab.ui.control.DropDown
        RunButton                  matlab.ui.control.Button
        OutputLabel                matlab.ui.control.Label
        SourceSelectButtonGroup_2  matlab.ui.container.ButtonGroup
        AllCharactersButton        matlab.ui.control.RadioButton
        CustomCharactersButton     matlab.ui.control.RadioButton
        EditFieldLabel             matlab.ui.control.Label
        charsField                 matlab.ui.control.EditField
        DecodeTab                  matlab.ui.container.Tab
        InputEditFieldLabel_2      matlab.ui.control.Label
        inputDeco                  matlab.ui.control.EditField
        CompressionAlgorithmDropDownLabel_2  matlab.ui.control.Label
        modeSelectDeco             matlab.ui.control.DropDown
        RunButtonDeco              matlab.ui.control.Button
        decodeGrp                  matlab.ui.container.ButtonGroup
        AllCharactersButton_2      matlab.ui.control.RadioButton
        CustomCharactersButton_2   matlab.ui.control.RadioButton
        EditFieldLabel_2           matlab.ui.control.Label
        charsDeco                  matlab.ui.control.EditField
        NumberofSymbolsinAdaptiveEditFieldLabel  matlab.ui.control.Label
        NumberofSymbolsinAdaptiveEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        charList = 'abcdefghijklmnopqrstuvwxyz,? ';
        % The full alphabet (probably). With the special chars and
        % everything. Added "period" to the text for debugging purposes.

        usedSrcEnco = ""; % The source which we will be using.
        usedSrcDeco = ""; % For decoding too.
    end

    methods (Access = private)
  function [coded, compRatio]= lempelZivEnco(app, src, charSrc)
            % Get the unique sequences:
            seqs = getUniqueSeq(app, src);

            % Get the Index/suffix pairs
            pairs = getIndexSuffixPair(app, seqs, charSrc);

            % Append both parts of the pair
            shiftFactor = 2^ceil(log2(numel(charSrc)));
            pairAppend =  shiftFactor * pairs(:,1) + pairs(:,2);

            % Get the number of bits required for each pair
            maxIndex = 0:length(seqs)-1;
            noBits = ceil(log2(maxIndex * shiftFactor + numel(charSrc)));


            for idx = 1:length(seqs)
                currentSeqNum = dec2bin(pairAppend(idx));
                codedArray(idx) = convertCharsToStrings(pad(currentSeqNum, noBits(idx), 'left','0')); 
            end

            coded = strjoin(codedArray);
            coded = strrep(coded, ' ', "");

            codedBitsPerSymbol = strlength(coded)/numel(src);
            fixedBitsPerSymbol = log2(shiftFactor);
            compRatio = codedBitsPerSymbol/fixedBitsPerSymbol;

        end

        function dict = getUniqueSeq(~, src)
            % Turns our input string into an array of unique sequences.
            dict = []; % This will keep hold of our unique sequences.
            letterIDX = 1;
            while letterIDX <= length(src)

                seqEndIDX = letterIDX; % Init

                % We use cell2mat because extractBetween returns a cell
                % array instead of a normal char vector.
                seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));

                while ismember(seq, dict)
                    if(seqEndIDX == length(src))
                        break
                    end
                    seqEndIDX = seqEndIDX + 1;
                    seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));
                end

                dict = [dict, convertCharsToStrings(seq)]; %#ok<*AGROW>
                letterIDX = seqEndIDX+1;
            end
        end


        function pairs = getIndexSuffixPair(~, seqArr, symbols)
            pairs = zeros(length(seqArr), 2);
            index = zeros(1, length(seqArr));
            suffix = strings(1, length(seqArr));
            for idx = 1:length(seqArr)

                seq = char(seqArr(idx));
                suffix(idx) = seq(end);

                % We need to find the location of the prefix in our
                % dictionary.

                if strlength(seq) == 1
                    prefix = 0; %#ok<NASGU> 
                    index(idx) = 0;
                else
                    prefix = convertCharsToStrings(seq(1:end-1));
                    foundAt = find(seqArr == prefix);
                    index(idx) =  foundAt(1);
                end

                % When we try to find a letter in the uniques array
                % we get an integer.
                suffixLocs = strfind(symbols, (suffix(idx)))-1;
                suffix(idx) = suffixLocs(1);
                pairs(idx, 1) = index(idx);
                pairs(idx, 2) = suffix(idx);
            end

        end

        function text = lempelZivDeco(app, src, charSrc)
            
            % Calculate maxPairs
            shiftFactor = 2^ceil(log2(numel(charSrc)));
            maxY = numel(charSrc);
            maxX = 0;
            maxBits = [];

            idx = 1;
            maxBitsIdx = 1;

            while idx <= length(src)
                % Calculate the word length
                wordLength = ceil(log2(shiftFactor*maxX + maxY));

                maxBits(maxBitsIdx) = wordLength;
                maxBitsIdx = maxBitsIdx+1;
                idx = idx + wordLength;
                maxX = maxX+1;
            end

            % Now maxBits has the number of bits that represent each
            % index/suffix pair. we now need to group the bits into
            % codewords, then decode the code words into index/suffix
            % pairs.

            codeWords = getCodeWord(app, src, maxBits);
            [index, suffix] = decodeIndexSuffixPairs(app, codeWords, charSrc);

            seqArr = strings(1, numel(codeWords));

            for idx = 1:numel(seqArr)
                if index(idx) == 0
                    seqArr(idx) = charSrc(suffix(idx)+1);
                else
                    seqArr(idx) = seqArr(index(idx))+charSrc(suffix(idx)+1);
                end
            end

            text = strjoin(seqArr, '\0');
        end

        function [indexArr, suffixArr] = decodeIndexSuffixPairs(~, codeWords, charSrc)
            % First, find how many bits does the suffix take up
            suffixLen = ceil(log2(numel(charSrc)));

            % Then, split the code words using suffixLen into two parts
            for idx = 1:numel(codeWords)
                codeWord = codeWords(idx);
                splitIndex = strlength(codeWord) - suffixLen;

                if splitIndex == 0
                    indexArr(idx) = "0";
                    suffixArr(idx) = codeWord;
                    continue
                end

                indexArr(idx) = convertCharsToStrings(codeWord{1}(1:splitIndex));
                suffixArr(idx) = convertCharsToStrings(codeWord{1}(splitIndex+1:end));


            end
            indexArr = bin2dec(indexArr);
            suffixArr = bin2dec(suffixArr);
        end

        function codeWords = getCodeWord(~, src, noOfBitsArr)
            codeWords = strings(1, numel(noOfBitsArr));

            idx = 1;
            iterator = 1;
            while idx <= length(src)
                codeWords(iterator) = convertCharsToStrings(cell2mat(extractBetween(src, idx, idx-1+noOfBitsArr(iterator))));
                idx = idx + noOfBitsArr(iterator);
                iterator = iterator+1;
            end

        end


       %adaptive arathmatic function encode
        function [encoded_str,Compression_Ratio] = Adaptive_Arithmatic_Encode(app,str,U_char)


            len = length(str);          % length of the string
            
            u = unique(lower(U_char));            % get unique characters(lowered) from the string
            
            len_unique = length(u);     % length of the unique characters string
            
            
            p = zeros(1, len_unique);
            
            for i = 1 : len_unique          %equalprob
                p(i) = 1 / len_unique;
            end
            
            
            % Encoder Table        
            low = 0;
            high = 1;
            stage = zeros(1, len_unique + 1);   %table
            stage(1)=low;
            stage(length(stage))=high;
            
            for i = 2: length(stage)-1
                
                stage(i)= stage(i-1)+p(i);      %initial states of the table
                
            end
            
            u = sort(u);
            prev_observation = zeros(1, len_unique);   %The number of ocurence of each symbol     
            p_sep = zeros(1, len_unique);       %the probability calculated after each sequence
            
            pointer_test=0
            if len > 16
                if str(1)==str(17)
                    pointer_test = 1;
                end
                
                
            end


            encoded_str='';
            
            if (len_unique <= 3 && len < 33) %%&& pointer_test == 0 %|| (len ~= (len_unique*2+1) && len ~= (len_unique*3+1))
                
                for i = 1 : len
                    for j = 1 : len_unique
                        
                        % if the value from 'str'
                        % matches with 'u' then
                        if str(i) == u(j)
                            prev_observation(j)=prev_observation(j) + 1;    %number of occurence increased by 1
                            
                            for k = 1 : len_unique
                                p_sep(k)=(1+prev_observation(k))/(i+len_unique); %the probability calculated after each sequence
                            end
                            
                            %pos = j;       %for debug
                            
                            low = stage(j);
                            high = stage(j+1);
                            
                            stage(1)=low;
                            stage(length(stage))=high;
                            for ind = 2: length(stage)-1
                                
                                stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);     %updating the table
                                
                                
                            end
                            
                            break
                            
                        end
                        
                    end
                    
                end
                
            else
                
                pointer=0;
                % The same as above, but encode each 10 chars sequentially
                pointervalue = 0;
                for i = 1 : len
                    
                    if (rem((i-1),10)==0 && i~=1)
                        pointer =1;
                        pointervalue = pointervalue + 1;
                        l_of_bin = ceil(log2(1/(high-low)))+1;
                        
                        tag = (low + high)/2;
                        
                        for i_encode=1:l_of_bin
                            
                            tag = tag * 2;
                            
                            if tag < 1
                                encoded_str = [encoded_str '0'];
                            else
                                encoded_str = [encoded_str '1'];
                                tag = tag - 1;
                            end
                            
                            
                        end
                        
                        
                        low = 0;
                        high = 1;
                        stage = zeros(1, len_unique + 1);   %table
                        stage(1)=low;
                        stage(length(stage))=high;
                        
                        for i_new = 2: length(stage)-1
                            
                            stage(i_new)= stage(i_new-1)+p(i_new);
                            
                        end
                        
                        u = sort(u);
                        prev_observation = zeros(1, len_unique);
                        p_sep = zeros(1, len_unique);
                        
                    end
                    
                    
                    for j = 1 : len_unique
                        
                        
                        if str(i) == u(j)
                            prev_observation(j)=prev_observation(j) + 1;
                            for k = 1 : len_unique
                                if pointer == 0
                                    p_sep(k)=(1+prev_observation(k))/(i+len_unique);
                                else
                                    p_sep(k)=(1+prev_observation(k))/((i-(10*pointervalue))+len_unique);
                                end
                            end
                            
                            low = stage(j);
                            high = stage(j+1);
                            
                            stage(1)=low;
                            stage(length(stage))=high;
                            for ind = 2: length(stage)-1
                                
                                stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
                                
                            end
                            
                            break
                        end
                    end


                end
            end
            
            
            % displaying tag value
            
            l_of_bin = ceil(log2(1/(high-low)))+1;
            
            tag = (low + high)/2;
            
            %convert from decimal to binary
            for i_encode=1:l_of_bin
                
                tag = tag * 2;
                
                if tag < 1
                    encoded_str = [encoded_str '0'];
                else
                    encoded_str = [encoded_str '1'];
                    tag = tag - 1;
                end
                
                
            end
            
            averg_codelength = length(encoded_str)/len ;
            Bits_Fixed_length = (log2(len_unique));
            %efficiency
            Compression_Ratio = averg_codelength/Bits_Fixed_length ;
            

        end

        %adaptive arathmatic function decode
        function Decoded = Adaptive_Arithmatic_Decode(app,code,len,symbol)

            %%decoding
            u = sort(lower(symbol));
            len_unique = length(u);

            indexlooper=50;

            for i=1:length(u)
                p(i)=1/len_unique ;
            end

            %%convert the encoding binary bits to decimal  

            decoded_str=0;
            encoded_str = convertStringsToChars(string(code));
            tester = encoded_str;
            tester = convertStringsToChars(tester);
            Output=char(num2cell(tester));
            Output=reshape(str2num(Output),1,[]) ;


            low = 0;
            high = 1;
            stage = zeros(1, len_unique + 1);   %table
            stage(1)=low;
            stage(length(stage))=high;

            for i = 2: length(stage)-1

                stage(i)= stage(i-1)+p(i);
    
            end


            prev_observation = zeros(1, len_unique);
            p_sep = zeros(1, len_unique);

            Decoded = '';


            if len_unique <=3


                for i = 1 : len
   
                    tag = sum(Output.*2.^(-1:-1:-length(Output)));
   
   
                    for j = 1 : len_unique
       
                            % If tag value falls between a certain 
                             % range in lookup table then
       
                             if tag > stage(j) && tag < stage(j+1)
          
                                 prev_observation(j)=prev_observation(j) + 1;

              
                                 for k = 1 : len_unique 
               
                                     p_sep(k)=(1+prev_observation(k))/(i+len_unique);
              
                                 end
           
           
                                 low = stage(j);
          
                                 high = stage(j+1);
           
           
                                 stage(1)=low;
           
                                 stage(length(stage))=high;
           
                                 for ind = 2: length(stage)-1       
               
                                     stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
           
                                 end
  
                                   % Geeting the matched tag 
                                     % value character
         
                                    decoded_str = u(j);
                                    Decoded = [Decoded decoded_str];


                                    break
       
                             end
   
                    end


                end


            else
                                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                                        pointer=0;
                
                                        pointervalue = 0;
               
                                        ending=0;
               
                                        iterator=0;
                
                                        for i = 1 : len
                   
                                            iterator=iterator + 1;
                   
                                            if i ==1
                    
                                                if len < 49
                                                    decoded_str = sum(Output.*2.^(-1:-1:-length(Output)));
                                                else
                                                    decoded_str = sum(Output(1:49).*2.^(-1:-1:-49));
                                                end


                                            end
                
                   
                                            if len-i < 10 && pointervalue ~= 0 && iterator > 10
                       
                                                indexlooper = indexlooper + 1;
                        
                                                decoded_str = sum(Output(indexlooper:(length(Output))).*2.^(-1:-1:-(length(Output)-indexlooper + 1)));
                        
                                                decoded_str= decoded_str/2;
                        
                                                pointervalue = 0;
                        
                                                ending = 1;

                        
                                                Decoded = [Decoded end_of_iterations(app,decoded_str,prev_observation,p_sep,stage,len_unique,p,len-i+1,u)];
                        
                                                break;
                   
                                            end


                                            if (rem((i-1),10)==0 && i~=1 && ending == 0)
                       
                                                pointer =1;
                        
                                                pointervalue = pointervalue + 1;
                       
                                                iterator = 1;
                        
                        
                                                if pointervalue == 2 
                           
                                                    decoded_str = sum(Output((99):(49+99)).*2.^(-1:-1:-(50)));
                            
                                                    indexlooper= indexlooper + 49;
                        
                                                elseif pointervalue == 3
                          
                                                    decoded_str = sum(Output((149):(199)).*2.^(-1:-1:-(51)));
                            
                                                    indexlooper= indexlooper + 53;
                      
                                                elseif pointervalue == 5
                            
                                                    indexlooper = indexlooper + 1;
                          
                                                    decoded_str = sum(Output(indexlooper:indexlooper +50).*2.^(-1:-1:-(51)));
                          
                                                    indexlooper = indexlooper + 50;
                       
                                                elseif pointervalue == 6
                           
                                                    indexlooper = indexlooper - 1;
                          
                                                    decoded_str = sum(Output(indexlooper:indexlooper +47).*2.^(-1:-1:-(48)));
                          
                                                    indexlooper = indexlooper + 47;
                       
                                                elseif pointervalue == 7  
                         
                                                    indexlooper= indexlooper + 2;
                         
                                                    decoded_str = sum(Output(indexlooper:indexlooper +49).*2.^(-1:-1:-(50)));
                         
                                                    indexlooper = indexlooper + 49;
                       
                                                elseif pointervalue == 8  
                          
                                                    indexlooper= indexlooper + 1;
                          
                                                    decoded_str = sum(Output(indexlooper:indexlooper +47).*2.^(-1:-1:-(48)));
                         
                                                    indexlooper = indexlooper + 47;
                      
                                                elseif pointervalue == 9  
                            
                          
                                                    decoded_str = sum(Output(indexlooper:indexlooper +47).*2.^(-1:-1:-(48)));
                           
                                                    indexlooper = indexlooper + 47;
                            
                       
                                                else
                          
                                                    decoded_str = sum(Output(indexlooper:48+indexlooper).*2.^(-1:-1:-(49)));
                             
                          
                                                    indexlooper= indexlooper + 48;
                       
                                                end

                       
                                                low = 0;
                        
                       
                                                high = 1;
                        
                       
                                                stage = zeros(1, len_unique + 1);   %table
                      
                                                stage(1)=low;
                       
                                                stage(length(stage))=high;
                        
                       
                                                for i_new = 2: length(stage)-1
                            
                          
                                                    stage(i_new)= stage(i_new-1)+p(i_new);
                            
                      
                                                end
                        
                       
                                                u = sort(u);
                       
                                                prev_observation = zeros(1, len_unique);
                      
                                                p_sep = zeros(1, len_unique);
                        
                       
                                            end


                                            for j = 1 : len_unique


                                                if decoded_str > stage(j) && decoded_str < stage(j+1)
                          
                                                    prev_observation(j)=prev_observation(j) + 1;
                            
                           
                                                    for k = 1 : length(u)
                            
                                                        if pointer == 0
                               
                                                            p_sep(k)=(1+prev_observation(k))/(i+len_unique);
                              
                                                        else
                                    
                               
                                                            p_sep(k)=(1+prev_observation(k))/((i-(10*pointervalue))+len_unique);
                             
                                                        end
                          
                                                    end


                                                    low = stage(j);
                           
                                                    high = stage(j+1);
                            
                           
                                                    stage(1)=low;
                           
                                                    stage(length(stage))=high;
                           
                                                    for ind = 2: length(stage)-1
                                
                         
                                                        stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
                         
                                                    end
                            
                           
                           %add the chars to each other
                                                    Decoded = [Decoded u(j)];


                                                    break
                       
                                                end
                   
                                            end


                                        end


            end


            Decoded = string(Decoded);
   
        end


        function Decoded=end_of_iterations(app,tag,prev_observation,p_sep,stage,len_unique,p,len,u)


        low = 0;

        high = 1;


        stage = zeros(1, len_unique + 1);   %table

        stage(1)=low;

        stage(length(stage))=high;


        for i = 2: length(stage)-1

            stage(i)= stage(i-1)+p(i);
    

        end


        prev_observation = zeros(1, len_unique);

        p_sep = zeros(1, len_unique);


        Decoded = '';


        for i = 1 : len


            for j = 1 : len_unique
       
                     % If tag value falls between a certain 
                     % range in table then
       
                     if tag > stage(j) && tag < stage(j+1)
         
                         prev_observation(j)=prev_observation(j) + 1;

            
                         for k = 1 : len_unique 
            
                             p_sep(k)=(1+prev_observation(k))/(i+len_unique);
           
                         end
           
          
                         low = stage(j);
          
                         high = stage(j+1);
           
          
                         stage(1)=low;
         
                         stage(length(stage))=high;
         
                         for ind = 2: length(stage)-1       
         
                             stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
        
                         end
  
                             % Geeting the matched tag 
                             % value character
          
                             decoded_str = u(j);

               
                             Decoded = [Decoded decoded_str];


                             break
       
                     end
   
            end


        end


        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            src = lower(app.input.Value); % Input source text


            selectedButton = app.SourceSelectButtonGroup_2.SelectedObject;
            if selectedButton.Text == "Custom Characters"
                app.usedSrcEnco = lower(app.charsField.Value);
            else
                app.usedSrcEnco = app.charList;
            end


            if app.modeSelect.Value == "Adaptive Arithmetic Coding"
                [code,compRatio] = app.Adaptive_Arithmatic_Encode(src,app.usedSrcEnco);
                title = "Copied To Clipboard, Compression Ratio = " + num2str(compRatio,4);
                uialert(app.UIFigure,code, title,"Icon","info" );
                app.OutputLabel.Text = num2str(compRatio*100,4);
                clipboard('copy', code);
            else
                [code, compRatio] = app.lempelZivEnco(src, app.usedSrcEnco);
                title = "Copied To Clipboard, Compression Ratio = " + num2str(compRatio,4);
                uialert(app.UIFigure,code, title,"Icon","info" );
                app.OutputLabel.Text = num2str(compRatio*100,4);
                clipboard('copy', code);
            end
        end

        % Button pushed function: RunButtonDeco
        function RunButtonDecoPushed(app, event)
            code = lower(app.inputDeco.Value); % Binary data to decode.
            number_of_symbols_ADP = app.NumberofSymbolsinAdaptiveEditField.Value;%number of sequence to stop

            selectedButton = app.decodeGrp.SelectedObject;
            if selectedButton.Text == "Custom Characters"
                app.usedSrcDeco = lower(app.charsDeco.Value);
            else
                app.usedSrcDeco = app.charList;
            end

            if app.modeSelectDeco.Value == "Adaptive Arithmetic Coding"
                text = app.Adaptive_Arithmatic_Decode(code, number_of_symbols_ADP ,app.usedSrcDeco);
                uialert(app.UIFigure, text, "Copied To Clipboard", "Icon", "info");
                clipboard("copy", text);
            else
                text = app.lempelZivDeco(code, app.usedSrcDeco);
                uialert(app.UIFigure, text, "Copied To Clipboard", "Icon", "info");
                clipboard("copy", text);
            end
        end

        % Selection changed function: SourceSelectButtonGroup_2
        function SourceSelectButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SourceSelectButtonGroup_2.SelectedObject;
            if selectedButton.Text == "Custom Characters"
                app.charsField.Enable = 'on';
            else
                app.charsField.Enable = 'off';
            end
        end

        % Callback function
        function SourceSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceSelectButtonGroup.SelectedObject;
            if selectedButton.Text == "Custom Characters"
                app.charsDeco.Enable = 'on';
            else
                app.charsDeco.Enable = 'off';
            end
        end

        % Selection changed function: decodeGrp
        function decodeGrpSelectionChanged(app, event)
            selectedButton = app.decodeGrp.SelectedObject;
            if selectedButton.Text == "Custom Characters"
                app.charsDeco.Enable = 'on';
            else
                app.charsDeco.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.InputEditFieldLabel = uilabel(app.EncodeTab);
            app.InputEditFieldLabel.Text = {'Input'; ''};
            app.input = uieditfield(app.EncodeTab, 'text');
            app.input.Value = 'If Peter Piper picked a peck of pickled peppers, where is the peck of pickled peppers Peter Piper picked?';
            app.CompressionAlgorithmDropDownLabel = uilabel(app.EncodeTab);
            app.CompressionAlgorithmDropDownLabel.Text = 'Compression Algorithm';
            app.modeSelect = uidropdown(app.EncodeTab);
            app.modeSelect.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelect.Value = 'Lempel-Ziv Coding';
            app.RunButton = uibutton(app.EncodeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputLabel = uilabel(app.EncodeTab);
            app.OutputLabel.Text = 'Output:';
            app.SourceSelectButtonGroup_2 = uibuttongroup(app.EncodeTab);
            app.SourceSelectButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SourceSelectButtonGroup_2SelectionChanged, true);
            app.SourceSelectButtonGroup_2.Title = 'Source Select';
            app.AllCharactersButton = uiradiobutton(app.SourceSelectButtonGroup_2);
            app.AllCharactersButton.Text = 'All Characters';
            app.AllCharactersButton.Value = true;
            app.CustomCharactersButton = uiradiobutton(app.SourceSelectButtonGroup_2);
            app.CustomCharactersButton.Text = 'Custom Characters';
            app.EditFieldLabel = uilabel(app.SourceSelectButtonGroup_2);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '';
            app.charsField = uieditfield(app.SourceSelectButtonGroup_2, 'text');
            app.charsField.Enable = 'off';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            app.InputEditFieldLabel_2 = uilabel(app.DecodeTab);
            app.InputEditFieldLabel_2.Text = {'Input'; ''};
            app.inputDeco = uieditfield(app.DecodeTab, 'text');
            app.inputDeco.Value = '0100000010100111000001111000001000001001110110001011011110001011110111111000100010000000000100000010100101000110011000001000001000110001010000110111000010111000101100010011010101101110111000010000100001000111100111100100000011010000111011000000001110011100100000110100000000100100001110011011100001000100000001000110111100000000011100100110111100000100010010101001000000000001101000001111010111100010111111110001011110011001010011110010010010000000010001001000010000100010010010100011011';
            app.CompressionAlgorithmDropDownLabel_2 = uilabel(app.DecodeTab);
            app.CompressionAlgorithmDropDownLabel_2.Text = 'Compression Algorithm';
            app.modeSelectDeco = uidropdown(app.DecodeTab);
            app.modeSelectDeco.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelectDeco.Value = 'Lempel-Ziv Coding';
            app.RunButtonDeco = uibutton(app.DecodeTab, 'push');
            app.RunButtonDeco.ButtonPushedFcn = createCallbackFcn(app, @RunButtonDecoPushed, true);
            app.RunButtonDeco.Text = 'Run';
            app.decodeGrp = uibuttongroup(app.DecodeTab);
            app.decodeGrp.SelectionChangedFcn = createCallbackFcn(app, @decodeGrpSelectionChanged, true);
            app.decodeGrp.Title = 'Source Select';
            app.AllCharactersButton_2 = uiradiobutton(app.decodeGrp);
            app.AllCharactersButton_2.Text = 'All Characters';
            app.AllCharactersButton_2.Value = true;
            app.CustomCharactersButton_2 = uiradiobutton(app.decodeGrp);
            app.CustomCharactersButton_2.Text = 'Custom Characters';
            app.EditFieldLabel_2 = uilabel(app.decodeGrp);
            app.EditFieldLabel_2.Enable = 'off';
            app.EditFieldLabel_2.Text = '';
            app.charsDeco = uieditfield(app.decodeGrp, 'text');
            app.charsDeco.Enable = 'off';
            app.NumberofSymbolsinAdaptiveEditFieldLabel = uilabel(app.DecodeTab);
            app.NumberofSymbolsinAdaptiveEditFieldLabel.Text = 'Number of Symbols in Adaptive';
            app.NumberofSymbolsinAdaptiveEditField = uieditfield(app.DecodeTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end